classdef appTanks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ControlsMiddle           matlab.ui.control.Label
        ControlsRight            matlab.ui.control.Label
        ControlsLeft             matlab.ui.control.Label
        ControlsP2Label          matlab.ui.control.Label
        ControlsP1Label          matlab.ui.control.Label
        ControlsTop              matlab.ui.control.Label
        RightplayerAICheckBox    matlab.ui.control.CheckBox
        LeftplayerAICheckBox     matlab.ui.control.CheckBox
        TargetFPSEditField       matlab.ui.control.NumericEditField
        TargetFPSEditFieldLabel  matlab.ui.control.Label
        StartNewGameButton       matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        adjustedFPS = 24
        closeRequested = 0
    end

    properties (Access = public)
        Objects
        terrain
        player1
        player2
    end

    methods (Access = private)

        function  adjustFPS(app, measuredFPS )
            if measuredFPS  < app.TargetFPSEditField.Value
                app.adjustedFPS = app.adjustedFPS + 0.2;
            else
                app.adjustedFPS = app.adjustedFPS - 0.2;
            end
        end
    end

    methods (Access = public)

        function announceExplosion(app, explosionBody)
            app.player1.checkExplosion(explosionBody)
            app.player2.checkExplosion(explosionBody)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Objects = GraphicalElementsList();
            app.Objects.addElement(app.ControlsTop);
            app.Objects.addElement(app.ControlsLeft);
            app.Objects.addElement(app.ControlsRight);
            app.Objects.addElement(app.ControlsMiddle);
            app.Objects.addElement(app.ControlsP1Label);
            app.Objects.addElement(app.ControlsP2Label);

            plot(app.UIAxes, [0,0],[100,100])
        end

        % Button pushed function: StartNewGameButton
        function StartNewGameButtonPushed(app, event)

            % Clear stored graphics
            cellfun(@delete, app.Objects.Elements)
            app.Objects.clearList()

            % Prepare plot
            cla(app.UIAxes)
            hold (app.UIAxes, 'on')

            % Generate Terrain and the background
            app.terrain = Terrain(app.UIAxes);
            shadowTerrain = copy(app.terrain);

            % Generate Tanks
            if app.RightplayerAICheckBox.Value
                app.player1 = AIControlledTank(app, ...
                    app.Objects, ...
                    struct('x', 90, 'y', 1), ...    % position
                    struct('x', 0, 'y', 0), ...     % velocity
                    struct('x', 0, 'y', 0), ...     % acceleration
                    0, ...                          % initial barell angle
                    1);                             % player ID
            else
                app.player1 = Tank(app, ...
                    app.Objects, ...
                    struct('x', 90, 'y', 1), ...
                    struct('x', 0, 'y', 0), ...
                    struct('x', 0, 'y', 0), ...
                    -0.25 * pi );
            end
            if app.LeftplayerAICheckBox.Value
                app.player2 = AIControlledTank(app, ...
                    app.Objects, ...
                    struct('x', 10, 'y', 99), ...
                    struct('x', 0, 'y', 0), ...
                    struct('x', 0, 'y', 0), ...
                    0, ...
                    2);
            else
                app.player2 = Tank(app, ...
                    app.Objects, ...
                    struct('x', 10, 'y', 99), ...
                    struct('x', 0, 'y', 0), ...
                    struct('x', 0, 'y', 0), ...
                    0.25 * pi);
            end

            app.Objects.addElement(app.terrain)
            app.Objects.addElement(app.player1);
            app.Objects.addElement(app.player2);

            % Plot graphics
            shadowTerrain.computeShadowDisplay()
            app.terrain.computeDisplay()

            cellfun(@computeDisplay, app.Objects.Elements)
            measuredFPS  = app.adjustedFPS;

            % Game loop
            while (1)
                tic;

                % Graphics update
                title(app.UIAxes, ("FPS: " + round(measuredFPS )))
                xlabel(app.UIAxes, ("Objects:" + length(app.Objects.Elements)))

                cellfun(@(x) updateState(x, app.Objects), app.Objects.Elements)
                cellfun(@updateDisplay, app.Objects.Elements)

                pause(1/app.adjustedFPS)

                % Ugly code to ensure App closes without error
                % TODO: google smarter solution
                try
                    if app.closeRequested
                        break
                    end
                catch
                    break
                end

                % FPS control
                loopTime = toc;
                measuredFPS  = .9*measuredFPS  + .1*(1/loopTime);
                app.adjustFPS(measuredFPS );
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

            % Player 1 controls
            if(strcmp(key, 'uparrow'))
                aimClock(app.player1)
            end
            if(strcmp(key, 'downarrow'))
                aimCounterClock(app.player1)
            end
            if(strcmp(key, 'leftarrow'))
                rideLeft(app.player1)
            end
            if(strcmp(key, 'rightarrow'))
                rideRight(app.player1)
            end

            % Player 2 controls
            if(strcmp(key, 'w'))
                aimCounterClock(app.player2)
            end
            if(strcmp(key, 's'))
                aimClock(app.player2)
            end
            if(strcmp(key, 'a'))
                rideLeft(app.player2)
            end
            if(strcmp(key, 'd'))
                rideRight(app.player2)
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;

            % Player 1 controls
            if(strcmp(key, 'uparrow'))
                stopAim(app.player1)
            end
            if(strcmp(key, 'downarrow'))
                stopAim(app.player1)
            end
            if(strcmp(key, 'leftarrow'))
                stopRide(app.player1)
            end
            if(strcmp(key, 'rightarrow'))
                stopRide(app.player1)
            end
            if(strcmp(key, 'l'))
                shoot(app.player1)
            end

            % Player 2 controls
            if(strcmp(key, 'w'))
                stopAim(app.player2)
            end
            if(strcmp(key, 's'))
                stopAim(app.player2)
            end
            if(strcmp(key, 'a'))
                stopRide(app.player2)
            end
            if(strcmp(key, 'd'))
                stopRide(app.player2)
            end
            if(strcmp(key, 'e'))
                shoot(app.player2)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.closeRequested = 1;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.StartNewGameButton = uibutton(app.UIFigure, 'push');
            app.StartNewGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewGameButtonPushed, true);
            app.StartNewGameButton.Text = 'Start a new game';
            app.TargetFPSEditFieldLabel = uilabel(app.UIFigure);
            app.TargetFPSEditFieldLabel.Text = 'Target FPS';
            app.TargetFPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetFPSEditField.Value = 24;
            app.LeftplayerAICheckBox = uicheckbox(app.UIFigure);
            app.LeftplayerAICheckBox.Text = 'Left player AI';
            app.RightplayerAICheckBox = uicheckbox(app.UIFigure);
            app.RightplayerAICheckBox.Text = 'Right player AI';
            app.ControlsTop = uilabel(app.UIFigure);
            app.ControlsTop.Text = 'Controls';
            app.ControlsP1Label = uilabel(app.UIFigure);
            app.ControlsP1Label.Text = 'Player 1';
            app.ControlsP2Label = uilabel(app.UIFigure);
            app.ControlsP2Label.Text = 'Player 2';
            app.ControlsLeft = uilabel(app.UIFigure);
            app.ControlsLeft.Text = {''; 'A/D'; 'W/S'; 'E'; ''};
            app.ControlsRight = uilabel(app.UIFigure);
            app.ControlsRight.Text = {''; 'Left/Right arrow'; 'Up/Down arrow'; 'L'; ''};
            app.ControlsMiddle = uilabel(app.UIFigure);
            app.ControlsMiddle.Text = {''; '<- MOVE ->'; '<- AIM ->'; '<- SHOOT ->'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTanks

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end